classdef engineDesignApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        MainTab                         matlab.ui.container.Tab
        ChamberPanel                    matlab.ui.container.Panel
        InitialRegressionRateEditFieldLabel  matlab.ui.control.Label
        InitialRegressionRateEditField  matlab.ui.control.NumericEditField
        FuelMassEditFieldLabel          matlab.ui.control.Label
        FuelMassEditField               matlab.ui.control.NumericEditField
        OxidiserMassEditFieldLabel      matlab.ui.control.Label
        OxidiserMassEditField           matlab.ui.control.NumericEditField
        InitialFuelMassFlowRateEditFieldLabel  matlab.ui.control.Label
        InitialFuelMassFlowRateEditField  matlab.ui.control.NumericEditField
        InitialOxidiserMassFlowRateEditFieldLabel  matlab.ui.control.Label
        InitialOxidiserMassFlowRateEditField  matlab.ui.control.NumericEditField
        CStarEditFieldLabel             matlab.ui.control.Label
        CStarEditField                  matlab.ui.control.NumericEditField
        PortLengthEditFieldLabel        matlab.ui.control.Label
        PortLengthEditField             matlab.ui.control.NumericEditField
        ThroatAreaEditFieldLabel        matlab.ui.control.Label
        ThroatAreaEditField             matlab.ui.control.NumericEditField
        ExitAreaEditFieldLabel          matlab.ui.control.Label
        ExitAreaEditField               matlab.ui.control.NumericEditField
        ExpansionRatioEditFieldLabel    matlab.ui.control.Label
        ExpansionRatioEditField         matlab.ui.control.NumericEditField
        InputsPanel                     matlab.ui.container.Panel
        ThrustEditFieldLabel            matlab.ui.control.Label
        ThrustEditField                 matlab.ui.control.NumericEditField
        TotalImpulseEditFieldLabel      matlab.ui.control.Label
        TotalImpulseEditField           matlab.ui.control.NumericEditField
        CombustionConfigurationButton   matlab.ui.control.Button
        InjectorConfigurationButton     matlab.ui.control.Button
        InjectorPanel                   matlab.ui.container.Panel
        InjectorTotalAreaEditFieldLabel  matlab.ui.control.Label
        InjectorTotalAreaEditField      matlab.ui.control.NumericEditField
        InjectorHoleDiameterEditFieldLabel  matlab.ui.control.Label
        InjectorHoleDiameterEditField   matlab.ui.control.NumericEditField
        NumberofHolesEditFieldLabel     matlab.ui.control.Label
        NumberofHolesEditField          matlab.ui.control.NumericEditField
        RunSimulationButton             matlab.ui.control.Button
        FullOutputTab                   matlab.ui.container.Tab
        CombustionConfigTab             matlab.ui.container.Tab
        ChamberPressureEditFieldLabel   matlab.ui.control.Label
        ChamberPressureEditField        matlab.ui.control.NumericEditField
        NozzleEfficiencyEditFieldLabel  matlab.ui.control.Label
        NozzleEfficiencyEditField       matlab.ui.control.NumericEditField
        FuelDensityEditFieldLabel       matlab.ui.control.Label
        FuelDensityEditField            matlab.ui.control.NumericEditField
        CombustionEfficiencyetacEditFieldLabel  matlab.ui.control.Label
        CombustionEfficiencyetacEditField  matlab.ui.control.NumericEditField
        MaxOxidiserMassFluxEditFieldLabel  matlab.ui.control.Label
        MaxOxidiserMassFluxEditField    matlab.ui.control.NumericEditField
        OFRatioEditFieldLabel           matlab.ui.control.Label
        OFRatioEditField                matlab.ui.control.NumericEditField
        InitialIspEditFieldLabel        matlab.ui.control.Label
        InitialIspEditField             matlab.ui.control.NumericEditField
        TOxEditFieldLabel               matlab.ui.control.Label
        TOxEditField                    matlab.ui.control.NumericEditField
        RegressionRateParametersPanel   matlab.ui.container.Panel
        aEditFieldLabel                 matlab.ui.control.Label
        aEditField                      matlab.ui.control.NumericEditField
        nEditFieldLabel                 matlab.ui.control.Label
        nEditField                      matlab.ui.control.NumericEditField
        mEditFieldLabel                 matlab.ui.control.Label
        mEditField                      matlab.ui.control.NumericEditField
        InjectorConfigTab               matlab.ui.container.Tab
        NumberofOrificesEditFieldLabel  matlab.ui.control.Label
        NumberofOrificesEditField       matlab.ui.control.NumericEditField
        OrificeTypeLabel                matlab.ui.control.Label
        OrificeType                     matlab.ui.control.DropDown
    end

    
    properties (Access = private)

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ThrustEditField.Value = 500;
            app.TotalImpulseEditField.Value = 10000;
            app.ChamberPressureEditField.Value = 35e5;
            app.NozzleEfficiencyEditField.Value = 0.9;
            app.FuelDensityEditField.Value = 950;
            app.CombustionEfficiencyetacEditField.Value = 0.85;
            app.MaxOxidiserMassFluxEditField.Value = 350;
            app.OFRatioEditField.Value = 7;
            app.InitialIspEditField.Value = 185;
            app.aEditField.Value = 0.000236;
            app.nEditField.Value = 0.605;
            app.mEditField.Value = 0;
            app.NumberofOrificesEditField.Value = 6;
            app.ThrustStatesSpinner.Value = 1;
            app.OrificeType.Value = app.OrificeType.Items(1);
            addpath(genpath('NitrousModelling\'));
        end

        % Button pushed function: CombustionConfigurationButton
        function CombustionConfigurationButtonPushed(app, event)
            %% Unpacking

            %Universal Constants
            g0    = 9.81;
            bar   = 1e5;
            P_amb = 1*bar;
            
            I_total = app.TotalImpulseEditField.Value;
            F_init = app.ThrustEditField.Value;
            t_burn  = I_total/F_init;
            
            P_cc     = app.ChamberPressureEditField.Value;
            %lambda   = rocketDesignParameters.lambda;
            rho_fuel = app.FuelDensityEditField.Value;
            etac     = app.CombustionEfficiencyetacEditField.Value;
            GO_max   = app.MaxOxidiserMassFluxEditField.Value;
            
            %InitialConfigVars
            OF       = app.OFRatioEditField.Value;
            T_req    = app.TOxEditField.Value;
            Isp_init = app.InitialIspEditField.Value;
            Isp_avg  = Isp_init*0.98;
            
            
            %regRate
            a = app.aEditField.Value;
            n = app.nEditField.Value;
            m = app.mEditField.Value;
            
            %% Start configuration
            
            
            %% Choose a OF, Then Mass and mass flows
            
            m_prop = I_total/(Isp_avg*g0); %[kg] RPE Ch.2
            m_ox = m_prop*OF/(OF+1);
            m_f = m_prop - m_ox;
            
            
            %% Calculate Flow rates
            [P_vap, ~, ~] = nitrous(T_req);
            
            P_vap = P_vap*bar; %convert to Pascals
            
            mdot_propinit = F_init/(Isp_init*g0);    %initial mass flow rate (SPAD eq 7.79)
            mdot_fuelinit = mdot_propinit/(1+OF); %[SPAD, eq 7.79]
            mdot_oxinit = mdot_propinit-mdot_fuelinit; %[SPAD, eq 7.79]
            
            %% Determine C* Cstar [PROPEP?]
            
            
            %This function calculates the values below.
            %[T_flame, gamma, m_mol, R,c_star] = thermochem(OF,P_cc,etac);
            [T_flame, gamma, m_mol, R,c_star] = paraffinThermo(etac);
            
            %gamma = 1.24; % ratio of specific heats (guess, but should be properly determined based on chosen O/F)
            %m_mol = 0.0262109; %Molar mass (kg/mol)
            %R = 8.314/m_mol; %Specific Gas Constant [SPAD, eq 7 .72]
            %T_flame = 3300; %[K] Guess!! check with [Propep]
            %c_star = etac*sqrt(gamma*R*T_flame)/(gamma*(2/(gamma+1))^((gamma+1)/(2*gamma-2))); %characteristic velocity [SPAD, eq 7.71]
            
            
            %% Configure Combustion Port
            %assume single cylindrical port
            
            GO_init = GO_max; %[kg/(m^2*s)] initial oxidiser flow flux [SPAD says blow-off/flooding limit is usually at 350-700 kg/m^2 s, so we used 350 to be safe]
            
            A_port = mdot_oxinit/GO_init; %[m^2] [SPAD, eq. 7.82]
            
            Diameter_port_init = 2*sqrt(A_port/pi); %[m] initial port diameter
            Perimeter_port = pi*Diameter_port_init;
            
            rocketDesign.port.IntialDiameter   = Diameter_port_init;
            rocketDesign.port.InitialPerimeter = Perimeter_port;
                    
            %Should perform a human check on the suitability of these numbers
            
            GF_init = GO_init/OF; %initial fuel flow flux [SPAD, eq 7.83]
            
            %regression rate formula takes form of:
            % r= a G^n L^m where the coefficients are defined in regRateParams.mat
            
            Lp = (mdot_fuelinit/(rho_fuel*a*(GO_init+GF_init)^n*Perimeter_port))^(1/(m+1)); %length of port (m) [SPAD, eq 7.88]
            
            
            %only for circular:
            
            Diameter_port_fin = sqrt((4*m_f)/(pi*Lp*rho_fuel)+Diameter_port_init^2);   %final diameter of the port [SPAD, eq 7.95]
            
            fuelweb=(Diameter_port_fin-Diameter_port_init)/2; %thickness of fuel that gets burnt [SPAD, 7.96]
            
            PortParameters = [Diameter_port_fin,fuelweb];
            
            rocketDesign.port.FinalDiameter=Diameter_port_fin;
            rocketDesign.port.InitialFuelWeb=fuelweb;
            
            r = a*((GO_init+GF_init)^n)*(Lp^m); %calculate reg rate for reference
            
            %% determine nozzle area
            
            %Since the combustion chamber diameter (dport final) and pressure is known,
            %we can determine the throat area required to choke the flow.
            
            A_throat = mdot_propinit*etac*sqrt(gamma*R*T_flame)*((gamma+1)/2)^((gamma+1)/(2*gamma-2))/(P_cc*gamma);
            
            %%% Use F = mdot Ve = mdot Me sqrt(gamma R T0/(1+(gamma-1)/2*Me^2)) and
            %%% then solve for Me
            
            syms Me;
            M_exit_target=double(vpasolve(mdot_propinit*Me*sqrt(gamma*R*T_flame/(1+(gamma-1)/2*Me^2)) == F_init, Me, 3)); %solve for required exit mach for desired thrust
            
            [~, ~, ~, ~, expansionRatio] = flowisentropic(gamma, M_exit_target,'mach'); %this is a function in the matlab aerospace toolbox
            
            A_exit = expansionRatio*A_throat;
            
            
            %% key outputs:
            app.InitialRegressionRateEditField.Value = r;
            
            app.CStarEditField.Value = c_star;
            
            app.FuelMassEditField.Value = m_f;
            
            app.OxidiserMassEditField.Value = m_ox;
            
            app.InitialFuelMassFlowRateEditField.Value = mdot_fuelinit;
            
            app.InitialOxidiserMassFlowRateEditField.Value = mdot_oxinit;
            
            app.PortLengthEditField.Value = Lp;
            
            app.ThroatAreaEditField.Value = A_throat;
            
            app.ExitAreaEditField.Value = A_exit;
            
            app.ExpansionRatioEditField.Value = expansionRatio;
            
        end

        % Button pushed function: InjectorConfigurationButton
        function InjectorConfigurationButtonPushed(app, event)
            %% Unpacking

            n = app.NumberofOrificesEditField.Value;
            throttle_states = 1;
            m_dot_nominal = app.InitialOxidiserMassFlowRateEditField.Value;
            orifice = app.OrificeType.Value;
            P_cc = app.ChamberPressureEditField.Value;
            
            dP = 0.2*P_cc; % 20% chamber pressure drop across injector
            
            rho = 725.3193;
            
            %% Setting head loss factor
            if isequal(orifice,'Sharp')
                
                k = 1.7; % Head loss factor, sharp-edged orifices
            
            elseif isequal(orifice,'Radiused')
                
                k = 1.3;
                
            else
                
                disp('Improper injector orifice type selection, please check spelling and supported orifice types')
                return
                
            end
            
            %% Throttling limits
            % Sets the mass flow rates for different specified throttle states.
            for i  = 1 : length(throttle_states)
                
                m_dot(i) = m_dot_nominal*throttle_states(i);
            
            end
            
            %% Unit Conversion
            m_doti = convmass(m_dot,'kg','lbm');
            dPi = convpres(dP,'Pa','psi');
            rhoi = convdensity(rho,'lbm/ft^3','kg/m^3');
            
            %% Area Calculation
            % Gives total injector area required, across all orifices
            
            Ai = m_doti.*sqrt((2.238*k)/(rhoi*dPi)); %result in in^2
            
            A = 0.00064516*Ai; % convert from in^2 to m^2
            A_small = A.*1000000; %mm^2 , good for debug
            
            %% Diameter Calculation
            % Gives the orifice diameter required to achieve the correct pressure drop
            % across the injector.
            
            di = ((3.627*k.*m_doti.^2)./(rhoi*dPi.*n.^2)).^0.25; %result in inches
            
            d = convlength(di,'in','m'); % convert from in to m
            d_small = d.*1000; %mm, good for debug
            
            %% Injector Velocity Calculation
            % WARNING: little information is given on this calculation in the source,
            % proceed with caution
            
            Vi = ((144.*m_doti)./(Ai.*rhoi)); % ft/s
            
            V = convvel(Vi,'ft/s','m/s');
            
            %% Set Outputs
%             injectorDesign.A_inj  = A;
%             injectorDesign.d_inj = d;
%             injectorDesign.V_inj = V;
            app.UITable.Data = table(A,d,V);
        end

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.ChamberPanel = uipanel(app.MainTab);
            app.ChamberPanel.Title = 'Chamber';
            app.InitialRegressionRateEditFieldLabel = uilabel(app.ChamberPanel);
            app.InitialRegressionRateEditFieldLabel.Text = 'Initial Regression Rate';
            app.InitialRegressionRateEditField = uieditfield(app.ChamberPanel, 'numeric');
            app.FuelMassEditFieldLabel = uilabel(app.ChamberPanel);
            app.FuelMassEditFieldLabel.Text = 'Fuel Mass';
            app.FuelMassEditField = uieditfield(app.ChamberPanel, 'numeric');
            app.OxidiserMassEditFieldLabel = uilabel(app.ChamberPanel);
            app.OxidiserMassEditFieldLabel.Text = 'Oxidiser Mass';
            app.OxidiserMassEditField = uieditfield(app.ChamberPanel, 'numeric');
            app.InitialFuelMassFlowRateEditFieldLabel = uilabel(app.ChamberPanel);
            app.InitialFuelMassFlowRateEditFieldLabel.Text = 'Initial Fuel Mass Flow Rate';
            app.InitialFuelMassFlowRateEditField = uieditfield(app.ChamberPanel, 'numeric');
            app.InitialOxidiserMassFlowRateEditFieldLabel = uilabel(app.ChamberPanel);
            app.InitialOxidiserMassFlowRateEditFieldLabel.Text = 'Initial Oxidiser Mass Flow Rate';
            app.InitialOxidiserMassFlowRateEditField = uieditfield(app.ChamberPanel, 'numeric');
            app.CStarEditFieldLabel = uilabel(app.ChamberPanel);
            app.CStarEditFieldLabel.Text = 'C Star';
            app.CStarEditField = uieditfield(app.ChamberPanel, 'numeric');
            app.PortLengthEditFieldLabel = uilabel(app.ChamberPanel);
            app.PortLengthEditFieldLabel.Text = 'Port Length';
            app.PortLengthEditField = uieditfield(app.ChamberPanel, 'numeric');
            app.ThroatAreaEditFieldLabel = uilabel(app.ChamberPanel);
            app.ThroatAreaEditFieldLabel.Text = 'Throat Area';
            app.ThroatAreaEditField = uieditfield(app.ChamberPanel, 'numeric');
            app.ExitAreaEditFieldLabel = uilabel(app.ChamberPanel);
            app.ExitAreaEditFieldLabel.Text = 'Exit Area';
            app.ExitAreaEditField = uieditfield(app.ChamberPanel, 'numeric');
            app.ExpansionRatioEditFieldLabel = uilabel(app.ChamberPanel);
            app.ExpansionRatioEditFieldLabel.Text = 'Expansion Ratio';
            app.ExpansionRatioEditField = uieditfield(app.ChamberPanel, 'numeric');
            app.InputsPanel = uipanel(app.MainTab);
            app.InputsPanel.Title = 'Inputs';
            app.ThrustEditFieldLabel = uilabel(app.InputsPanel);
            app.ThrustEditFieldLabel.Text = 'Thrust';
            app.ThrustEditField = uieditfield(app.InputsPanel, 'numeric');
            app.TotalImpulseEditFieldLabel = uilabel(app.InputsPanel);
            app.TotalImpulseEditFieldLabel.Text = 'Total Impulse';
            app.TotalImpulseEditField = uieditfield(app.InputsPanel, 'numeric');
            app.CombustionConfigurationButton = uibutton(app.MainTab, 'push');
            app.CombustionConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @CombustionConfigurationButtonPushed, true);
            app.CombustionConfigurationButton.Text = 'Combustion Configuration';
            app.InjectorConfigurationButton = uibutton(app.MainTab, 'push');
            app.InjectorConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @InjectorConfigurationButtonPushed, true);
            app.InjectorConfigurationButton.Text = 'Injector Configuration';
            app.InjectorPanel = uipanel(app.MainTab);
            app.InjectorPanel.Title = 'Injector';
            app.InjectorTotalAreaEditFieldLabel = uilabel(app.InjectorPanel);
            app.InjectorTotalAreaEditFieldLabel.Text = 'Injector Total Area';
            app.InjectorTotalAreaEditField = uieditfield(app.InjectorPanel, 'numeric');
            app.InjectorHoleDiameterEditFieldLabel = uilabel(app.InjectorPanel);
            app.InjectorHoleDiameterEditFieldLabel.Text = 'Injector Hole Diameter';
            app.InjectorHoleDiameterEditField = uieditfield(app.InjectorPanel, 'numeric');
            app.NumberofHolesEditFieldLabel = uilabel(app.InjectorPanel);
            app.NumberofHolesEditFieldLabel.Text = 'Number of Holes';
            app.NumberofHolesEditField = uieditfield(app.InjectorPanel, 'numeric');
            app.RunSimulationButton = uibutton(app.MainTab, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.FullOutputTab = uitab(app.TabGroup);
            app.FullOutputTab.Title = 'Full Output';
            app.CombustionConfigTab = uitab(app.TabGroup);
            app.CombustionConfigTab.Title = 'Combustion Config';
            app.ChamberPressureEditFieldLabel = uilabel(app.CombustionConfigTab);
            app.ChamberPressureEditFieldLabel.Text = 'Chamber Pressure';
            app.ChamberPressureEditField = uieditfield(app.CombustionConfigTab, 'numeric');
            app.NozzleEfficiencyEditFieldLabel = uilabel(app.CombustionConfigTab);
            app.NozzleEfficiencyEditFieldLabel.Text = 'Nozzle Efficiency';
            app.NozzleEfficiencyEditField = uieditfield(app.CombustionConfigTab, 'numeric');
            app.FuelDensityEditFieldLabel = uilabel(app.CombustionConfigTab);
            app.FuelDensityEditFieldLabel.Text = 'Fuel Density';
            app.FuelDensityEditField = uieditfield(app.CombustionConfigTab, 'numeric');
            app.CombustionEfficiencyetacEditFieldLabel = uilabel(app.CombustionConfigTab);
            app.CombustionEfficiencyetacEditFieldLabel.Text = 'Combustion Efficiency (etac)';
            app.CombustionEfficiencyetacEditField = uieditfield(app.CombustionConfigTab, 'numeric');
            app.MaxOxidiserMassFluxEditFieldLabel = uilabel(app.CombustionConfigTab);
            app.MaxOxidiserMassFluxEditFieldLabel.Text = 'Max Oxidiser Mass Flux';
            app.MaxOxidiserMassFluxEditField = uieditfield(app.CombustionConfigTab, 'numeric');
            app.OFRatioEditFieldLabel = uilabel(app.CombustionConfigTab);
            app.OFRatioEditFieldLabel.Text = 'OF Ratio';
            app.OFRatioEditField = uieditfield(app.CombustionConfigTab, 'numeric');
            app.InitialIspEditFieldLabel = uilabel(app.CombustionConfigTab);
            app.InitialIspEditFieldLabel.Text = 'Initial Isp';
            app.InitialIspEditField = uieditfield(app.CombustionConfigTab, 'numeric');
            app.TOxEditFieldLabel = uilabel(app.CombustionConfigTab);
            app.TOxEditFieldLabel.Text = 'TOx';
            app.TOxEditField = uieditfield(app.CombustionConfigTab, 'numeric');
            app.RegressionRateParametersPanel = uipanel(app.CombustionConfigTab);
            app.RegressionRateParametersPanel.Title = 'Regression Rate Parameters';
            app.aEditFieldLabel = uilabel(app.RegressionRateParametersPanel);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.RegressionRateParametersPanel, 'numeric');
            app.nEditFieldLabel = uilabel(app.RegressionRateParametersPanel);
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.RegressionRateParametersPanel, 'numeric');
            app.mEditFieldLabel = uilabel(app.RegressionRateParametersPanel);
            app.mEditFieldLabel.Text = 'm';
            app.mEditField = uieditfield(app.RegressionRateParametersPanel, 'numeric');
            app.InjectorConfigTab = uitab(app.TabGroup);
            app.InjectorConfigTab.Title = 'Injector Config';
            app.NumberofOrificesEditFieldLabel = uilabel(app.InjectorConfigTab);
            app.NumberofOrificesEditFieldLabel.Text = 'Number of Orifices';
            app.NumberofOrificesEditField = uieditfield(app.InjectorConfigTab, 'numeric');
            app.OrificeTypeLabel = uilabel(app.InjectorConfigTab);
            app.OrificeTypeLabel.Text = 'Orifice Type';
            app.OrificeType = uidropdown(app.InjectorConfigTab);
            app.OrificeType.Items = {'Sharp', 'Radiused'};
            app.OrificeType.Value = 'Sharp';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = engineDesignApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end